classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TorqueSwitch               matlab.ui.control.Switch
        TorqueSwitchLabel          matlab.ui.control.Label
        StatusPanel                matlab.ui.container.Panel
        StatusLabel                matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        ControlPanel               matlab.ui.container.Panel
        SingularityWarningLabel    matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
        AbortButton                matlab.ui.control.Button
        ZeroButton                 matlab.ui.control.Button
        InitButton                 matlab.ui.control.Button
        ProgramArgumentsPanel      matlab.ui.container.Panel
        ProgramArgumentsLabel      matlab.ui.control.Label
        ProgramArgumentsEditField  matlab.ui.control.EditField
        ProgramSelectPanel         matlab.ui.container.Panel
        ProgramSelectLabel         matlab.ui.control.Label
        ProgramSelectDropdown      matlab.ui.control.DropDown
        PortDropDown               matlab.ui.control.DropDown
        PortDropDownLabel          matlab.ui.control.Label
        ConnectDisconnectButton    matlab.ui.control.Button
    end


    properties (Access = private)
        launcher;
    end

    methods (Access = private)

        function addPrograms(app)
            % Get program names from the Launcher instance
            programNames = app.launcher.programNames;

            % Filter out 'Plotting' program
            programNames = programNames(~strcmp(programNames, 'Plotting'));

            % Convert remaining program names to a cell array of character vectors
            dropdownItems = cellfun(@char, programNames, 'UniformOutput', false);

            % Set the filtered items in the ProgramDropDown dropdown
            app.ProgramSelectDropdown.Items = dropdownItems;
        end

        function isPlottingProgram = checkIfCurrentProgramIsPlotting(app)
            % Initialize the return variable
            isPlottingProgram = false;

            % Check if there is a current program instance
            if ~isempty(app.launcher.currentProgramInstance)
                % Check the 'name' property of the current program instance
                if strcmp(app.launcher.currentProgramInstance.name, 'Plotting')
                    isPlottingProgram = true;
                end
            end
        end

        function closeControlPanel(app)
            % Hide Control Panel
            app.ControlPanel.Visible = "off";
            % Reset Values
            app.ProgramArgumentsEditField.Value = '';
        end

        function openProgramPanel(app)
            % Show Control Panel
            app.ControlPanel.Visible = "on";
            % Reload Values Panel
            app.ProgramSelectDropdownValueChanged;
        end

        function resumePlotting(app)
            app.checkSingularity;
            app.launcher.instantiateProgram('Plotting');
            app.launcher.currentProgramInstance.execute;
        end

        function goZeroAndDisconnect(app)

            app.StatusLabel.Text = 'Disconnecting...';

            app.launcher.realRobot.torqueEnable;
            app.ZeroButtonPushed;

            app.launcher.disconnect();
            app.StatusLabel.Text = 'Disconnected';
            app.StatusLamp.Color = [1, 0, 0]; % Red color

            app.ConnectDisconnectButton.Text = 'Connect';

            % Hide Torque Switch
            app.TorqueSwitch.Visible = 'off';
            app.closeControlPanel;

            % Close Figure
            close all

        end

        function connectAndSetZero(app)
            selectedPort = app.PortDropDown.Value;
            try
                % Try to Connect
                app.launcher.connect(selectedPort);
                % Set Lamp to Connected
                app.StatusLabel.Text = 'Connected';
                app.StatusLamp.Color = [0, 1, 0]; % Green color
                app.ConnectDisconnectButton.Text = 'Disconnect';
                % Show Torque Panel
                app.TorqueSwitch.Value = 'On';
                app.TorqueSwitch.Visible = 'on';
                app.TorqueSwitchValueChanged;

                % Set Zero Position To Current Positoin
                app.launcher.realRobot.setZeroPositionToCurrentPosition;

                % Start Plotting
                app.resumePlotting;

            catch
                % Set Lamp to Error
                app.StatusLabel.Text = 'Error';
                app.StatusLamp.Color = [1, 0, 0]; % Red color
                % Hide Torque Switch
                app.TorqueSwitch.Visible = 'off';
                app.closeControlPanel;

            end
        end

        function is_valid = checkProgramArguments(app)
            selectedProgram = app.ProgramSelectDropdown.Value;
            arguments = app.ProgramArgumentsEditField.Value;

            % Initialize validity flag
            is_valid = false;

            switch selectedProgram
                case 'Set_Joints'
                    % Expecting 4 doubles (including negatives) separated by commas or semicolons
                    expression = '^(-?\d+(\.\d+)?[,;] *){3}-?\d+(\.\d+)?$';
                    if regexp(arguments, expression)
                        is_valid = true;
                    else
                        % Display UI warning for incorrect format
                        uialert(app.UIFigure, ...
                            'Please enter 4 joint angles (including negatives) separated by commas or semicolons.', ...
                            'Invalid Input', ...
                            'Icon', 'warning');
                    end

                case 'Set_Position'
                    % Expecting 3 doubles (including negatives) separated by commas or semicolons
                    expression = '^(-?\d+(\.\d+)?[,;] *){2}-?\d+(\.\d+)?$';
                    if regexp(arguments, expression)
                        is_valid = true;
                    else
                        % Display UI warning for incorrect format
                        uialert(app.UIFigure, ...
                            'Please enter 3 position coordinates (x, y, z) including negatives, separated by commas or semicolons.', ...
                            'Invalid Input', ...
                            'Icon', 'warning');
                    end

                case 'Trajectory_2D'
                    % No arguments needed for Trajectory_2D
                    is_valid = true;

                otherwise
                    % Handle unexpected program selection
                    uialert(app.UIFigure, ...
                        'Unexpected program selected.', ...
                        'Error', ...
                        'Icon', 'error');
            end
        end


        function is_singularity = checkSingularity(app)
            % Check Singularity
            J = app.launcher.virtualRobot.getJacobianNumeric;
            if cond(J) > 20
                is_singularity = true;
                app.SingularityWarningLabel.Text = 'Singularity Warning';
            else
                is_singularity = false;
                app.SingularityWarningLabel.Text = '';
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            screenSize = get(0, 'ScreenSize'); % Get the screen size
            appWidth = screenSize(3) / 3; % App width is 1/3 of the screen width
            appHeight = screenSize(4); % App height is the full screen height

            % Set the position of the app
            app.UIFigure.Position = [screenSize(3) - appWidth, 1, appWidth, appHeight];

            % Set UIFigure to visible
            app.UIFigure.Visible = 'on';

            % Add Path to Launcher
            currentDir = fileparts(mfilename('fullpath'));
            launcherPath = fullfile(currentDir, 'src', 'Programs');
            addpath(launcherPath);

            % Create a Launcher Instance, ensures deletion of old instance
            app.launcher = Launcher();

            % Add properly defined programs to the list of programs
            % selectable in the dropdown. (It adds the name property).
            app.addPrograms;

        end

        % Button pushed function: ConnectDisconnectButton
        function ConnectDisconnectButtonPushed(app, event)

            if isempty(app.launcher.realRobot)
                % Connect button
                app.connectAndSetZero;

            else % Disconnect Button
                app.goZeroAndDisconnect;
            end
        end

        % Value changed function: TorqueSwitch
        function TorqueSwitchValueChanged(app, event)
            value = app.TorqueSwitch.Value;

            %% Todo: The switch should not change if callback is canceld
            % Does it do this?


            if strcmp(value, 'On')
                % If the switch is set to 'On', enable the robot's torque
                app.launcher.realRobot.torqueEnable;
                % And open the control panel
                app.openProgramPanel;
            elseif strcmp(value, 'Off')
                % If the switch is set to 'Off', disable running programs
                % (except plotting)
                if ~app.checkIfCurrentProgramIsPlotting
                    app.launcher.stopCurrentProgram;
                end
                % Disable the torque
                app.launcher.realRobot.torqueDisable;
                % Close the control panel
                app.closeControlPanel;
            end
        end

        % Value changed function: ProgramSelectDropdown
        function ProgramSelectDropdownValueChanged(app, event)
            % Value changed function: ProgramDropDown
            selectedProgram = app.ProgramSelectDropdown.Value;

            % Clear the Program Arguments Field
            app.ProgramArgumentsEditField.Value = '';

            % Handle different cases based on the selected program
            switch selectedProgram
                case 'Set_Joints'
                    app.ProgramArgumentsPanel.Visible = true;
                    app.ProgramArgumentsLabel.Text = 'Joint Angles [deg]';
                    app.ProgramArgumentsEditField.Placeholder = 'q1, q2, q3, q4';

                case 'Set_Position'
                    app.ProgramArgumentsPanel.Visible = true;
                    app.ProgramArgumentsLabel.Text = 'TCP Position [mm]';
                    app.ProgramArgumentsEditField.Placeholder = 'x, y, z';

                case 'Trajectory_2D'
                    app.ProgramArgumentsPanel.Visible = false;
                    app.ProgramArgumentsLabel.Text = '';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.launcher.realRobot)
                % Display alert dialog
                uialert(app.UIFigure, ...
                    'Please disconnect the robot before closing the application.', ...
                    'Disconnect Required', ...
                    'Icon', 'warning');

                % Do not close the app, user must manually disconnect first
            else
                % No active connection, safe to close
                delete(app);
            end
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            app.launcher.instantiateProgram('Set_Joints')
            app.launcher.currentProgramInstance.execute([pi/8;pi/8;pi/8;pi/8]);
            app.resumePlotting;
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            app.launcher.instantiateProgram('Set_Joints')
            app.launcher.currentProgramInstance.execute([0;0;0;0]);
            app.resumePlotting;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)

            % Method to handle the Go button push event
            % Check if the supplied arguments in the ProgramArgumentsEditField are in the correct format and valid
            is_valid = app.checkProgramArguments;

            if is_valid
                % Extract the selected program
                selectedProgram = app.ProgramSelectDropdown.Value;
                % Get arguments as a string
                argumentsStr = app.ProgramArgumentsEditField.Value;

                % Convert string arguments to numeric array
                numericArguments = str2double(split(regexprep(argumentsStr, '[,;]', ' '), ' '));

                % Reshape numericArguments to ensure it is a column vector
                numericArguments = reshape(numericArguments, [], 1);

                % Call the appropriate program with the arguments
                switch selectedProgram
                    case 'Set_Joints'
                        % Instantiate and execute the Set_Joints program
                        app.launcher.instantiateProgram('Set_Joints');
                        % Transform degree to rad
                        numericArguments_rad = deg2rad(numericArguments);
                        app.launcher.currentProgramInstance.execute(numericArguments_rad);

                    case 'Set_Position'
                        % Instantiate and execute the Set_Position program
                        app.launcher.instantiateProgram('Set_Position');
                        app.launcher.currentProgramInstance.execute(numericArguments);

                    case 'Trajectory_2D'
                        % Instantiate and execute the Trajectory_2D program
                        app.launcher.instantiateProgram('Trajectory_2D');
                        app.launcher.currentProgramInstance.execute();

                    otherwise
                        % Handle unexpected program selection
                        uialert(app.UIFigure, ...
                            'Unexpected program selected.', ...
                            'Error', ...
                            'Icon', 'error');
                end

                % Resume plotting if it was previously interrupted
                app.resumePlotting;

            end


        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.launcher.realRobot.setJointVelocities([0;0;0;0]);
            app.launcher.stopCurrentProgram;
            app.resumePlotting;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectDisconnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDisconnectButtonPushed, true);
            app.ConnectDisconnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.PortDropDown.Value = 'COM3';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Visible = 'off';
            app.ProgramSelectPanel = uipanel(app.ControlPanel);
            app.ProgramSelectDropdown = uidropdown(app.ProgramSelectPanel);
            app.ProgramSelectDropdown.ValueChangedFcn = createCallbackFcn(app, @ProgramSelectDropdownValueChanged, true);
            app.ProgramSelectLabel = uilabel(app.ProgramSelectPanel);
            app.ProgramSelectLabel.Text = 'Program';
            app.ProgramArgumentsPanel = uipanel(app.ControlPanel);
            app.ProgramArgumentsPanel.Title = 'Panel';
            app.ProgramArgumentsEditField = uieditfield(app.ProgramArgumentsPanel, 'text');
            app.ProgramArgumentsEditField.Placeholder = 'q1, q2, q3, q4';
            app.ProgramArgumentsLabel = uilabel(app.ProgramArgumentsPanel);
            app.ProgramArgumentsLabel.Text = 'Value';
            app.InitButton = uibutton(app.ControlPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.ZeroButton = uibutton(app.ControlPanel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.AbortButton = uibutton(app.ControlPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.GoButton = uibutton(app.ControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.SingularityWarningLabel = uilabel(app.ControlPanel);
            app.SingularityWarningLabel.Text = 'Singularity Warning';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Panel2';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Disconnected';
            app.TorqueSwitchLabel = uilabel(app.UIFigure);
            app.TorqueSwitchLabel.Visible = 'off';
            app.TorqueSwitchLabel.Text = 'Torque';
            app.TorqueSwitch = uiswitch(app.UIFigure, 'slider');
            app.TorqueSwitch.ValueChangedFcn = createCallbackFcn(app, @TorqueSwitchValueChanged, true);
            app.TorqueSwitch.Visible = 'off';
            app.TorqueSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end